classdef testmlapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        errorEditField        matlab.ui.control.EditField
        errorEditFieldLabel   matlab.ui.control.Label
        resultEditField       matlab.ui.control.NumericEditField
        resultEditFieldLabel  matlab.ui.control.Label
        clcButton             matlab.ui.control.Button
        Button_5              matlab.ui.control.StateButton
        xButton               matlab.ui.control.StateButton
        Button_3              matlab.ui.control.StateButton
        Button_2              matlab.ui.control.StateButton
        num1EditField_2       matlab.ui.control.NumericEditField
        num1EditField_2Label  matlab.ui.control.Label
        num2EditField         matlab.ui.control.NumericEditField
        num2EditFieldLabel    matlab.ui.control.Label
        resultButton          matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: resultButton
        function resultButtonPushed(app, event)
            global plus minus multiply divide;
            a=app.num1EditField_2.Value;
            b=app.num2EditField.Value;
            app.errorEditField.Value='';
            if plus ==1
                app.resultEditField.Value=a+b;
            elseif minus ==1 
                app.resultEditField.Value=a-b;
            elseif multiply ==1
                try
                app.resultEditField.Value=a*b;
                catch
                    app.errorEditField.Value='inf*0';
                end
            elseif  divide ==1
                try
                app.resultEditField.Value=a/b;
                catch 
                    app.resultEditField.Value=0;
                    if a==0
                    app.errorEditField.Value='0/0';
                    else
                        app.errorEditField.Value='inf/inf';
                    end
                end
            else 
                app.resultEditField.Value=0;
                app.errorEditField.Value='no operator';
            end


        end

        % Value changed function: num2EditField
        function num2EditFieldValueChanged(app, event)
            % value

            
        end

        % Value changed function: num1EditField_2
        function num1EditField_2ValueChanged(app, event)
            value = app.num1EditField_2.Value;
            
        end

        % Value changed function: Button_2
        function Button_2ValueChanged(app, event)
            global plus minus multiply divide;
            value = app.Button_2.Value;
            if value==1
                plus=1;
                divide=0;
                minus=0;
                multiply=0;
                app.Button_5.Value=0;
                app.xButton.Value=0;
                app.Button_3.Value=0;
                app.Button_2.Value=1;
            else
                plus=0;
            end
            % plus
        end

        % Value changed function: Button_3
        function Button_3ValueChanged(app, event)
            global plus minus multiply divide;
            value = app.Button_3.Value;
            if value==1
                plus=0;
                divide=0;
                minus=1;
                multiply=0;
                app.Button_5.Value=0;
                app.xButton.Value=0;
                app.Button_3.Value=1;
                app.Button_2.Value=0;
            else 
                minus=0;
            end
            % minus
        end

        % Value changed function: xButton
        function xButtonValueChanged(app, event)
            global plus minus multiply divide;
            value = app.xButton.Value;
            if value==1
                plus=0;
                divide=0;
                minus=0;
                multiply=1;
                app.Button_5.Value=0;
                app.xButton.Value=1;
                app.Button_3.Value=0;
                app.Button_2.Value=0;
            else
                multiply=0;
            end
            % multiply
        end

        % Value changed function: Button_5
        function Button_5ValueChanged(app, event)
            global plus minus multiply divide;
            value = app.Button_5.Value;
            if value==1
                plus=0;
                divide=1;
                minus=0;
                multiply=0;
                app.Button_5.Value=1;
                app.xButton.Value=0;
                app.Button_3.Value=0;
                app.Button_2.Value=0;
            else 
                divide=0;
            end
            % divide
        end

        % Value changed function: resultEditField
        function resultEditFieldValueChanged(app, event)
            value = app.resultEditField.Value;
            
        end

        % Value changed function: errorEditField
        function errorEditFieldValueChanged(app, event)
            value = app.errorEditField.Value;
            
        end

        % Button pushed function: clcButton
        function clcButtonPushed(app, event)
            app.num1EditField_2.Value=0;
            app.num2EditField.Value=0;
            app.resultEditField.Value=0;
            app.errorEditField.Value='';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.resultButton = uibutton(app.UIFigure, 'push');
            app.resultButton.ButtonPushedFcn = createCallbackFcn(app, @resultButtonPushed, true);
            app.resultButton.Text = 'result';
            app.num2EditFieldLabel = uilabel(app.UIFigure);
            app.num2EditFieldLabel.Text = 'num 2';
            app.num2EditField = uieditfield(app.UIFigure, 'numeric');
            app.num2EditField.ValueChangedFcn = createCallbackFcn(app, @num2EditFieldValueChanged, true);
            app.num1EditField_2Label = uilabel(app.UIFigure);
            app.num1EditField_2Label.Text = 'num 1';
            app.num1EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.num1EditField_2.ValueChangedFcn = createCallbackFcn(app, @num1EditField_2ValueChanged, true);
            app.Button_2 = uibutton(app.UIFigure, 'state');
            app.Button_2.ValueChangedFcn = createCallbackFcn(app, @Button_2ValueChanged, true);
            app.Button_2.Text = '+';
            app.Button_3 = uibutton(app.UIFigure, 'state');
            app.Button_3.ValueChangedFcn = createCallbackFcn(app, @Button_3ValueChanged, true);
            app.Button_3.Text = '-';
            app.xButton = uibutton(app.UIFigure, 'state');
            app.xButton.ValueChangedFcn = createCallbackFcn(app, @xButtonValueChanged, true);
            app.xButton.Text = 'x';
            app.Button_5 = uibutton(app.UIFigure, 'state');
            app.Button_5.ValueChangedFcn = createCallbackFcn(app, @Button_5ValueChanged, true);
            app.Button_5.Text = '/';
            app.clcButton = uibutton(app.UIFigure, 'push');
            app.clcButton.ButtonPushedFcn = createCallbackFcn(app, @clcButtonPushed, true);
            app.clcButton.Text = 'clc';
            app.resultEditFieldLabel = uilabel(app.UIFigure);
            app.resultEditFieldLabel.Text = 'result';
            app.resultEditField = uieditfield(app.UIFigure, 'numeric');
            app.resultEditField.ValueChangedFcn = createCallbackFcn(app, @resultEditFieldValueChanged, true);
            app.errorEditFieldLabel = uilabel(app.UIFigure);
            app.errorEditFieldLabel.Text = 'error';
            app.errorEditField = uieditfield(app.UIFigure, 'text');
            app.errorEditField.ValueChangedFcn = createCallbackFcn(app, @errorEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = testmlapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end